classdef DirectorySelector < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        ThedirectorymadefromthesetupfunctionLabel  matlab.ui.control.Label
        SelectMatricesforAnalysisButton  matlab.ui.control.Button
        DirectoryPathEditField       matlab.ui.control.EditField
        DirectoryPathEditFieldLabel  matlab.ui.control.Label
        SelectDirectorywithDMFilesLabel  matlab.ui.control.Label
        SelectDirectoryButton        matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SelectDirectoryButton
        function SelectDirectoryButtonPushed(app, event)
            selectedDir = uigetdir('', 'Select a directory');
            if selectedDir ~= 0
                app.DirectoryPathEditField.Value = selectedDir;
            end
        end

        % Button pushed function: SelectMatricesforAnalysisButton
        function SelectMatricesforAnalysisButtonPushed(app, event)
            assignin("base", "filepath", app.DirectoryPathEditField.Value)
            filepath=app.DirectoryPathEditField.Value;
            if ~exist(fullfile(string, 'fDM.mat')) && ~exist(fullfile(filepath, 'eDM.mat')) && ~exist(fullfile(filepath, 'feDM.mat')) &&  ~exist(fullfile(filepath, 'cDM.mat'))
                    uialert(app.UIFigure, sprintf('Please try another dirctory or run the Set Up function first.\nMissing .mat files in:\n %s \n ', filepath), 'Error: Missing Necessary files');
                    return
            else
                delete(app.UIFigure);
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SelectDirectoryButton = uibutton(app.UIFigure, 'push');
            app.SelectDirectoryButton.ButtonPushedFcn = createCallbackFcn(app, @SelectDirectoryButtonPushed, true);
            app.SelectDirectoryButton.Text = 'Select Directory';
            app.SelectDirectorywithDMFilesLabel = uilabel(app.UIFigure);
            app.SelectDirectorywithDMFilesLabel.Text = 'Select Directory with DM Files:';
            app.DirectoryPathEditFieldLabel = uilabel(app.UIFigure);
            app.DirectoryPathEditFieldLabel.Text = 'Directory Path:';
            app.DirectoryPathEditField = uieditfield(app.UIFigure, 'text');
            app.DirectoryPathEditField.Value = 'Enter Path Here or Select with the Above Button';
            app.SelectMatricesforAnalysisButton = uibutton(app.UIFigure, 'push');
            app.SelectMatricesforAnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @SelectMatricesforAnalysisButtonPushed, true);
            app.SelectMatricesforAnalysisButton.Text = 'Select Matrices for Analysis';
            app.ThedirectorymadefromthesetupfunctionLabel = uilabel(app.UIFigure);
            app.ThedirectorymadefromthesetupfunctionLabel.Text = '(The directory made from the set up function)';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DirectorySelector

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end